classdef Main_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SimulacinbiolgicaLabel        matlab.ui.control.Label
        ElectroencefalografaEEGLabel  matlab.ui.control.Label
        Button_3                      matlab.ui.control.Button
        SeleccionaunadelassiguientesopcionesLabel  matlab.ui.control.Label
        simulacion_biologica          matlab.ui.control.Button
        RedesneuronalesLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        estructura_neuronas % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Redes Neuronales';            %Creación de estructura con los tipos de neuronas
            app.estructura_neuronas(1).tipo = 'Tonic Spiking';
            app.estructura_neuronas(2).tipo = 'Phasic Spiking';
            app.estructura_neuronas(3).tipo = 'Tonic Bursting';
            app.estructura_neuronas(4).tipo = 'Phasic Bursting';
            app.estructura_neuronas(5).tipo = 'Mixed Mode';
            app.estructura_neuronas(6).tipo = 'Spike Frequency Adaptative';
            app.estructura_neuronas(7).tipo = 'Spike Latency';
            app.estructura_neuronas(8).tipo = 'Class 1 Excitable';
            app.estructura_neuronas(9).tipo = 'Class 2 Excitable';
            app.estructura_neuronas(10).tipo = 'Subthreshold';
            app.estructura_neuronas(11).tipo = 'Resonator';
            app.estructura_neuronas(12).tipo = 'Integrator';
            app.estructura_neuronas(13).tipo = 'Rebound Spike';
            app.estructura_neuronas(14).tipo = 'Rebound Burst';
            app.estructura_neuronas(15).tipo = 'Threshold Variability';
            app.estructura_neuronas(16).tipo = 'Bistability';
            app.estructura_neuronas(17).tipo = 'DAP';
            app.estructura_neuronas(18).tipo = 'Accomodation';
            for i=1:18
                app.estructura_neuronas(i).eleccion = 0;
            end

            app.estructura_neuronas(1).eleccion = 1;
            app.estructura_neuronas(2).eleccion = 1;
            app.estructura_neuronas(3).eleccion = 1;
            app.estructura_neuronas(4).eleccion = 1;

        end

        % Button pushed function: simulacion_biologica
        function simulacion_biologicaPushed(app, event)
            menu_red_simulada(app.estructura_neuronas)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedesneuronalesLabel = uilabel(app.UIFigure);
            app.RedesneuronalesLabel.Text = 'Redes neuronales';
            app.simulacion_biologica = uibutton(app.UIFigure, 'push');
            app.simulacion_biologica.ButtonPushedFcn = createCallbackFcn(app, @simulacion_biologicaPushed, true);
            app.simulacion_biologica.Icon = 'conexiones_eeg.jpg';
            app.simulacion_biologica.Tooltip = {'Red neuronal simulada'};
            app.simulacion_biologica.Text = '';
            app.SeleccionaunadelassiguientesopcionesLabel = uilabel(app.UIFigure);
            app.SeleccionaunadelassiguientesopcionesLabel.Text = 'Selecciona una de las siguientes opciones';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Icon = 'eeg_symbol.jpg';
            app.Button_3.Tooltip = {'Adquisición de señales reales de EEG'};
            app.Button_3.Text = '';
            app.ElectroencefalografaEEGLabel = uilabel(app.UIFigure);
            app.ElectroencefalografaEEGLabel.Text = 'Electroencefalografía (EEG)';
            app.SimulacinbiolgicaLabel = uilabel(app.UIFigure);
            app.SimulacinbiolgicaLabel.Text = 'Simulación biológica';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end