classdef grafica_tipo_neurona < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        grafica   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        type_neuron; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, type)
            app.UIFigure.Name = 'Gráfica de neurona';
            app.type_neuron = type;
            if app.type_neuron == 1
                a=0.02; b=0.2; c=-65; d=6; V=-70; u=b*V; VV=[];  uu=[];
                tau = 0.25; tspan = 0:tau:100;
                T1=tspan(end)/10;
                for t=tspan
                    if (t>T1) 
                        I=14;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30; V = c; u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 max(tspan)],-90+[0 0 10 10],'Parent',app.grafica);
                title('Tonic spiking','Parent',app.grafica);
                axis(app.grafica,'off')
            elseif app.type_neuron == 2
                a=0.02; b=0.25; c=-65;  d=6; V=-64; u=b*V; VV=[];  uu=[];
                tau = 0.25; tspan = 0:tau:200; T1=20;
                for t=tspan
                    if (t>T1) 
                        I=0.5;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 max(tspan)],-90+[0 0 10 10],'Parent',app.grafica);
                title('Phasic spiking','Parent',app.grafica);
                %axis([0 max(tspan) -90 30])
                axis(app.grafica,'off');
            elseif app.type_neuron == 3
                a=0.02; b=0.2;  c=-50;  d=2; V=-70;  u=b*V; VV=[];  uu=[];
                tau = 0.1; tspan = 0:tau:220; T1=22;
                for t=tspan
                    if (t>T1 ) 
                        I=15;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 max(tspan)],-90+[0 0 10 10],'Parent',app.grafica);
                axis(app.grafica,'off');
                title('Tonic Bursting','Parent',app.grafica);         
            elseif app.type_neuron == 4
                a=0.02; b=0.25; c=-55;  d=0.05; V=-64;  u=b*V; VV=[];  uu=[];
                tau = 0.2;  tspan = 0:tau:200; T1=20;
                for t=tspan
                    if (t>T1) 
                        I=0.6;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 max(tspan)],-90+[0 0 10 10],'Parent',app.grafica);
                axis(app.grafica,'off');
                title('Phasic bursting','Parent',app.grafica);
            elseif app.type_neuron == 5
                a=0.02; b=0.2;  c=-55;  d=4; V=-70;  u=b*V; VV=[];  uu=[];
                tau = 0.25; tspan = 0:tau:160; T1=tspan(end)/10;
                for t=tspan
                    if (t>T1) 
                        I=10;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 max(tspan)],-90+[0 0 10 10],'Parent',app.grafica);
                axis(app.grafica, 'off');
                title('Mixed Mode','Parent',app.grafica);
            elseif app.type_neuron == 6
                a=0.01; b=0.2;  c=-65;  d=8; V=-70;  u=b*V; VV=[];  uu=[];
                tau = 0.25; tspan = 0:tau:85; T1=tspan(end)/10;
                for t=tspan
                    if (t>T1) 
                        I=30;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 max(tspan)],-90+[0 0 10 10],'Parent',app.grafica);
                axis(app.grafica, 'off');
                title('Spike Frequency Adaptative','Parent',app.grafica);
            elseif app.type_neuron == 7
                a=0.02; b=0.2;  c=-65;  d=6; V=-70;  u=b*V; VV=[];  uu=[];
                tau = 0.2; tspan = 0:tau:100; T1=tspan(end)/10;
                for t=tspan
                    if t>T1 && t < T1+3 
                        I=7.04;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 T1+3 T1+3 max(tspan)],-90+[0 0 10 10 0 0],'Parent',app.grafica);
                axis(app.grafica, 'off');
                title('Spike Latency','Parent',app.grafica);
            elseif app.type_neuron == 8
                a=0.02; b=-0.1; c=-55; d=6; V=-60; u=b*V; VV=[]; uu=[];
                tau = 0.25; tspan = 0:tau:300; T1=30;
                for t=tspan
                    if (t>T1) 
                        I=(0.075*(t-T1)); 
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+4.1*V+108-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 max(tspan) max(tspan)],-90+[0 0 20 0],'Parent',app.grafica);
                axis(app.grafica, 'off');
                title('Class 1 Excitable','Parent',app.grafica);
            elseif app.type_neuron == 9
                a=0.2;  b=0.26; c=-65;  d=0; V=-64;  u=b*V; VV=[];  uu=[];
                tau = 0.25; tspan = 0:tau:300; T1=30;
                for t=tspan
                    if (t>T1) 
                        I=-0.5+(0.015*(t-T1)); 
                    else
                        I=-0.5;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 max(tspan) max(tspan)],-90+[0 0 20 0],'Parent',app.grafica);
                axis(app.grafica,'off');
                title('Class 2 Excitable','Parent',app.grafica);
            elseif app.type_neuron == 10
                a=0.05; b=0.26; c=-60;  d=0; V=-62;  u=b*V;
                VV=[];  uu=[];
                tau = 0.25; tspan = 0:tau:200;
                T1=tspan(end)/10;
                for t=tspan
                    if (t>T1) && (t < T1+5) 
                        I=2;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 (T1+5) (T1+5) max(tspan)],-90+[0 0 10 10 0 0],...
                      tspan(220:end),-10+20*(VV(220:end)-mean(VV)),'Parent',app.grafica);
                axis(app.grafica,'off');
                title('Subthreshold Osc.','Parent',app.grafica);
            elseif app.type_neuron == 11
                a=0.1;  b=0.26; c=-60;  d=-1; V=-62;  u=b*V; VV=[];  uu=[];
                tau = 0.25; tspan = 0:tau:400; T1=tspan(end)/10;
                T2=T1+20; T3 = 0.7*tspan(end); T4 = T3+40;
                for t=tspan
                    if ((t>T1) && (t < T1+4)) || ((t>T2) && (t < T2+4)) || ((t>T3) && (t < T3+4)) || ((t>T4) && (t < T4+4)) 
                        I=0.65;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 (T1+8) (T1+8) T2 T2 (T2+8) (T2+8)...
                    T3 T3 (T3+8) (T3+8) T4 T4 (T4+8) (T4+8) max(tspan)],...
                    -90+[0 0 10 10 0 0 10 10 0 0 10 10 0 0 10 10 0 0],...
                    'Parent',app.grafica)
                axis(app.grafica,'off')
                title('Resonator','Parent',app.grafica);
            elseif app.type_neuron == 12
                a=0.02; b=-0.1; c=-55; d=6; V=-60; u=b*V; VV=[];  uu=[];
                tau = 0.25; tspan = 0:tau:100; T1=tspan(end)/11; T2=T1+5;
                T3 = 0.7*tspan(end); T4 = T3+10;
                for t=tspan
                    if ((t>T1) && (t < T1+2)) || ((t>T2) && (t < T2+2)) || ((t>T3) && (t < T3+2)) || ((t>T4) && (t < T4+2)) 
                        I=9;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+4.1*V+108-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 (T1+2) (T1+2) T2 T2 (T2+2) (T2+2)...
                    T3 T3 (T3+2) (T3+2) T4 T4 (T4+2) (T4+2) max(tspan)],...
                    -90+[0 0 10 10 0 0 10 10 0 0 10 10 0 0 10 10 0 0],...
                    'Parent',app.grafica);
                axis(app.grafica,'off')
                title('Integrator','Parent',app.grafica);
            elseif app.type_neuron == 13
                a=0.03; b=0.25; c=-60;  d=4; V=-64;  u=b*V; VV=[];  uu=[];
                tau = 0.2;  tspan = 0:tau:200; T1=20;
                for t=tspan
                    if (t>T1) && (t < T1+5) 
                        I=-15;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 (T1+5) (T1+5) max(tspan)],...
                    -85+[0 0 -5 -5 0 0],'Parent',app.grafica);
                axis(app.grafica,'off');
                title('Rebound Spike','Parent',app.grafica);
            elseif app.type_neuron == 14
                a=0.03; b=0.25; c=-52;  d=0;
                V=-64;  u=b*V; VV=[];  uu=[];
                tau = 0.2;  tspan = 0:tau:200; T1=20;
                for t=tspan
                    if (t>T1) && (t < T1+5) 
                        I=-15;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 (T1+5) (T1+5) max(tspan)],...
                    -85+[0 0 -5 -5 0 0],'Parent',app.grafica);
                axis(app.grafica,'off');
                title('Rebound Burst','Parent',app.grafica);
            elseif app.type_neuron == 15
                a=0.03; b=0.25; c=-60;  d=4; V=-64;  u=b*V; VV=[];  uu=[];
                tau = 0.25; tspan = 0:tau:100;
                for t=tspan
                   if ((t>10) && (t < 15)) || ((t>80) && (t < 85)) 
                        I=1;
                    elseif (t>70) && (t < 75)
                        I=-6;
                    else
                        I=0;
                   end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 10 10 15 15 70 70 75 75 80 80 85 85 max(tspan)],...
                          -85+[0 0  5  5  0  0  -5 -5 0  0  5  5  0  0],...
                          'Parent',app.grafica);
                axis(app.grafica,'off')
                title('Threshold Variability','Parent',app.grafica);
            elseif app.type_neuron == 16
                a=0.1;  b=0.26; c=-60;  d=0; V=-61;  u=b*V; VV=[];  uu=[];
                tau = 0.25; tspan = 0:tau:300; T1=tspan(end)/8; T2 = 216;
                for t=tspan
                    if ((t>T1) && (t < T1+5)) || ((t>T2) && (t < T2+5)) 
                        I=1.24;
                    else
                        I=0.24;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1 T1 (T1+5) (T1+5) T2 T2 (T2+5) (T2+5)...
                    max(tspan)],-90+[0 0 10 10 0 0 10 10 0 0],'Parent',...
                    app.grafica);
                axis(app.grafica,'off');
                title('Bistability','Parent',app.grafica);
            elseif app.type_neuron == 17
                a=1;  b=0.2; c=-60;  d=-21; V=-70;  u=b*V; VV=[];  uu=[];
                tau = 0.1; tspan = 0:tau:50; T1 = 10;
                for t=tspan
                     if abs(t-T1)<1 
                        I=20;
                    else
                        I=0;
                     end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*V-u);
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                end
                plot(tspan,VV,[0 T1-1 T1-1 T1+1 T1+1 max(tspan)],...
                    -90+[0 0 10 10 0 0],'Parent',app.grafica);
                axis(app.grafica,'off')
                title('DAP','Parent',app.grafica);
            elseif app.type_neuron == 18
                a=0.02;  b=1; c=-55;  d=4; V=-65;  u=-16;
                VV=[];  uu=[];  II=[]; tau = 0.5; tspan = 0:tau:400;
                for t=tspan
                    if (t < 200)
                        I=t/25;
                    elseif t < 300
                        I=0;
                    elseif t < 312.5
                        I=(t-300)/12.5*4;
                    else
                        I=0;
                    end
                    V = V + tau*(0.04*V^2+5*V+140-u+I);
                    u = u + tau*a*(b*(V+65));
                    if V > 30
                        VV(end+1)=30;
                        V = c;
                        u = u + d;
                    else
                        VV(end+1)=V;
                    end
                    uu(end+1)=u;
                    II(end+1)=I;
                end
                plot(tspan,VV,tspan,II*1.5-90,'Parent',app.grafica);
                axis(app.grafica,'off')
                title('Accomodation','Parent',app.grafica);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Title')
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            zlabel(app.grafica, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grafica_tipo_neurona(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end