classdef input_simulado < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        IntroduzcalossiguientesparmetrosLabel  matlab.ui.control.Label
        NeuronasinhibidorasEditField  matlab.ui.control.NumericEditField
        NeuronasinhibidorasEditFieldLabel  matlab.ui.control.Label
        RealizarconexionesButton      matlab.ui.control.Button
        EferenciasEditField           matlab.ui.control.NumericEditField
        EferenciasEditFieldLabel      matlab.ui.control.Label
        AferenciasEditField           matlab.ui.control.NumericEditField
        AferenciasEditFieldLabel      matlab.ui.control.Label
        NeuronasexcitadorasEditField  matlab.ui.control.NumericEditField
        NeuronasexcitadorasEditFieldLabel  matlab.ui.control.Label
        RedneuronalbiolgicasimuladaLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cant_neuro_ex;
        cant_neuro_in;
        cant_neuro_total;% Description
        cant_entradas;
        cant_salidas;
        logic_in;
        logic_sal;
        intensidad;
        time;
        estructura_base;
        estructura;
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            app.UIFigure.Name = 'Parámetros de red';
        end

        % Button pushed function: RealizarconexionesButton
        function RealizarconexionesButtonPushed(app, event)
            startupFcn(app)
            hold(app.UIAxes,'off')
            app.UIAxes.View = [-37.5, -30];
            app.cant_neuro_ex = app.NeuronasexcitadorasEditField.Value;
            app.cant_neuro_in = app.NeuronasinhibidorasEditField.Value;
            app.cant_neuro_total = app.cant_neuro_ex + app.cant_neuro_in;
            app.cant_entradas = app.AferenciasEditField.Value;
            app.cant_salidas = app.EferenciasEditField.Value;
            app.logic_sal = app.cant_neuro_total*app.cant_salidas;
            app.logic_in = app.cant_neuro_total*app.cant_entradas;
            if app.logic_in < app.logic_sal
                msgbox('No hay suficiente espacio para conexiones. Introduzca más aferencias')
            elseif app.intensidad == 0
                msgbox('El impulso inicial debe ser mayor que 0% de intensidad')
            else
                app.Image.Visible = 'off';
                 %mapa_topografico(app.cant_neuro_total, app.cant_neuro_ex, app.cant_neuro_in)
                vect_cell_excitadoras=zeros(1,app.cant_neuro_ex);
                vect_papel(1,1:app.cant_neuro_in)=-1;
                vect_papel(1,app.cant_neuro_in+1:(app.cant_neuro_in+app.cant_neuro_ex))=1;
                orden_papeles=randsample(vect_papel,length(vect_papel));
                pos_vect=0;
                for i=1:app.cant_neuro_total
                    app.estructura(i).neurona=sprintf('neurona # %i',i);
                    app.estructura(i).entradas=[];
                    app.estructura(i).salidas=[];
                    app.estructura(i).modo=orden_papeles(i);
                    if orden_papeles(i)==1
                        app.estructura(i).papel='excitadora';
                        pos_vect=pos_vect+1; 
                        vect_cell_excitadoras(pos_vect)=i;
                    
                    elseif orden_papeles(i)==-1
                        app.estructura(i).papel='inhibidora';  
                    end
                end
    
                 coloract=rand(1,3);
                 coloract(1)=.8;
                 coloract(3)=.8;
                 colorinh=rand(1,3);
                 colorinh(1)=.2;
                 colorinh(3)=.2;
                %%graficar 3D
                for i=1:app.cant_neuro_total
                    app.estructura(i).etiqueta=i;
                    app.estructura(i).posicion=ceil(rand(1,3)*70);
                    [app.estructura(i).formax,app.estructura(i).formay,app.estructura(i).formaz]=sphere(20);
                    posmod=app.estructura(i).posicion;
                    app.estructura(i).formax=app.estructura(i).formax+2*posmod(1);
                    app.estructura(i).formay=app.estructura(i).formay+2*posmod(2);
                    app.estructura(i).formaz=app.estructura(i).formaz+2*posmod(3);
                    app.estructura(i).salidasact=0;
                    app.estructura(i).entradasact=0;
                end
    
                repet=0;
                combinatoria=1:app.cant_neuro_total;
                for i=1:app.cant_neuro_total
                    terminarya=0;
                    a=1;    
                    salidas_prueba = randsample(combinatoria,app.cant_salidas);                                
                    for q=1:length(salidas_prueba) 
                        neuronaeval=salidas_prueba(q);
                        verneuro=app.estructura(neuronaeval).entradasact;
                        vectentradas=app.estructura(neuronaeval).entradas;
                        for qwer=1:length(vectentradas)
                            if app.estructura(i).etiqueta==vectentradas(qwer)                                       
                                repet=1;
                            end
                        end
                        while(verneuro>=app.cant_entradas||neuronaeval==i||repet==1)
                            repet=0;
                            neuronaeval=randsample(combinatoria,1); 
                            verneuro=app.estructura(neuronaeval).entradasact;
                            vectentradas=app.estructura(neuronaeval).entradas;
                            for qwer=1:verneuro
                                if app.estructura(i).etiqueta==vectentradas(qwer)                                          
                                    repet=1;
                                end
                            end
                            a=1+a;
                            if a>=7000
                                terminarya=1;
                                break;
                            end
                        end
                    
                        if terminarya==0
                            salidas_prueba(q)=neuronaeval; 
                            app.estructura(neuronaeval).entradasact=app.estructura(neuronaeval).entradasact+1;
                            posconect=app.estructura(neuronaeval).entradas;
                            posconect(app.estructura(neuronaeval).entradasact)=i;
                            app.estructura(neuronaeval).entradas=posconect; 
                            
                            app.estructura(i).salidasact=app.estructura(i).salidasact+1;
                            possal=app.estructura(i).salidas;
                            possal(app.estructura(i).salidasact)=neuronaeval;
                            app.estructura(i).salidas=possal;
                        else
                            break;
                        end
                    end                                 
                 end
                 a=1;
                xticks(app.UIAxes,[])
                yticks(app.UIAxes,[])
                zticks(app.UIAxes,[])
                title("Mapa topográfico de conexiones neuronales",'Parent',app.UIAxes)
                hold(app.UIAxes,"on")
                app.UIAxes.Visible = 'on';
                for i=1:app.cant_neuro_total
                    if app.estructura(i).modo==1
                        plot3(app.estructura(i).formax,app.estructura(i).formay,...
                            app.estructura(i).formaz,'Color',coloract,'Parent',app.UIAxes)
                    elseif app.estructura(i).modo==-1
                        plot3(app.estructura(i).formax,app.estructura(i).formay,...
                            app.estructura(i).formaz,'Color',colorinh,'Parent',app.UIAxes)
                    end        
                    posmod=app.estructura(i).posicion;
                    postext(1)=posmod(1)*2;
                    postext(2)=posmod(2)*2;
                    postext(3)=posmod(3)*2;
                    postext=postext+2;
                    neurona=strcat('#',sprintf('%i',app.estructura(i).etiqueta));
                    text(app.UIAxes,postext(1),postext(2),postext(3),neurona, 'Rotation',+15)
                end
                     [flecx,flecy,flecz]=sphere(5);
                     flecxor=flecx./3;
                     flecyor=flecy./3;
                     fleczor=flecz./3;
                     for i=1:app.cant_neuro_total
                    linetext=zeros(1,3);
                         for linebucle=1:app.estructura(i).salidasact
                             origen=app.estructura(i).posicion*2;
                             neuronadest=app.estructura(i).salidas;
                             neuronatarget=neuronadest(linebucle);
                             destino=app.estructura(neuronatarget).posicion*2;
                             hold(app.UIAxes,'on')
                             l1=[origen(1),destino(1)];
                             l1vect=linspace(origen(1),destino(1),20);
                             posflecx=flecxor+l1vect(19);
                             l2=[origen(2),destino(2)];
                             l2vect=linspace(origen(2),destino(2),20);
                             posflecy=flecyor+l2vect(19);
                             l3=[origen(3),destino(3)];
                             l3vect=linspace(origen(3),destino(3),20);
                             posflecz=fleczor+l3vect(19);
                    
                             if app.estructura(i).modo==1
                                 line(app.UIAxes,l1,l2,l3,'Color',coloract)
                                 plot3(posflecx,posflecy,posflecz,'Color','red','Parent',app.UIAxes)  
                             elseif app.estructura(i).modo==-1
                                line(app.UIAxes,l1,l2,l3,'Color',colorinh)
                                plot3(posflecx,posflecy,posflecz,'Color','red','Parent',app.UIAxes)        
                             end  
                             pause(.01)
                         end
                     end
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RedneuronalbiolgicasimuladaLabel = uilabel(app.UIFigure);
            app.RedneuronalbiolgicasimuladaLabel.Text = 'Red neuronal biológica simulada';
            app.NeuronasexcitadorasEditFieldLabel = uilabel(app.UIFigure);
            app.NeuronasexcitadorasEditFieldLabel.Text = 'Neuronas excitadoras';
            app.NeuronasexcitadorasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NeuronasexcitadorasEditField.Limits = [1 50];
            app.NeuronasexcitadorasEditField.RoundFractionalValues = 'on';
            app.NeuronasexcitadorasEditField.Value = 1;
            app.AferenciasEditFieldLabel = uilabel(app.UIFigure);
            app.AferenciasEditFieldLabel.Text = 'Aferencias';
            app.AferenciasEditField = uieditfield(app.UIFigure, 'numeric');
            app.AferenciasEditField.Limits = [1 10];
            app.AferenciasEditField.RoundFractionalValues = 'on';
            app.AferenciasEditField.Value = 1;
            app.EferenciasEditFieldLabel = uilabel(app.UIFigure);
            app.EferenciasEditFieldLabel.Text = 'Eferencias';
            app.EferenciasEditField = uieditfield(app.UIFigure, 'numeric');
            app.EferenciasEditField.Limits = [1 10];
            app.EferenciasEditField.RoundFractionalValues = 'on';
            app.EferenciasEditField.Value = 1;
            app.RealizarconexionesButton = uibutton(app.UIFigure, 'push');
            app.RealizarconexionesButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarconexionesButtonPushed, true);
            app.RealizarconexionesButton.Text = 'Realizar conexiones';
            app.NeuronasinhibidorasEditFieldLabel = uilabel(app.UIFigure);
            app.NeuronasinhibidorasEditFieldLabel.Text = 'Neuronas inhibidoras';
            app.NeuronasinhibidorasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NeuronasinhibidorasEditField.Limits = [1 50];
            app.NeuronasinhibidorasEditField.RoundFractionalValues = 'on';
            app.NeuronasinhibidorasEditField.Value = 1;
            app.IntroduzcalossiguientesparmetrosLabel = uilabel(app.UIFigure);
            app.IntroduzcalossiguientesparmetrosLabel.Text = 'Introduzca los siguientes parámetros';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'icono_conexiones.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = input_simulado

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end