classdef tipos_neuronas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_2                         matlab.ui.control.Label
        PresionaelbotnLabel             matlab.ui.control.Label
        NotaLabel                       matlab.ui.control.Label
        example_icono                   matlab.ui.control.Button
        accomodation                    matlab.ui.control.Button
        DAP                             matlab.ui.control.Button
        bistability                     matlab.ui.control.Button
        rebound_burst                   matlab.ui.control.Button
        threshold_variability           matlab.ui.control.Button
        rebound_spike                   matlab.ui.control.Button
        integrator                      matlab.ui.control.Button
        class1_excitable                matlab.ui.control.Button
        phasic_bursting                 matlab.ui.control.Button
        resonator                       matlab.ui.control.Button
        spike_latency                   matlab.ui.control.Button
        tonic_bursting                  matlab.ui.control.Button
        subthreshold                    matlab.ui.control.Button
        spike_freq_adap                 matlab.ui.control.Button
        phasic_spiking                  matlab.ui.control.Button
        class2_excitable                matlab.ui.control.Button
        mixed_mode                      matlab.ui.control.Button
        grafica_tonic_spiking           matlab.ui.control.Button
        indicacion                      matlab.ui.control.Label
        AccomodationSwitch              matlab.ui.control.Switch
        AccomodationSwitchLabel         matlab.ui.control.Label
        DAPSwitch                       matlab.ui.control.Switch
        DAPSwitchLabel                  matlab.ui.control.Label
        BistabilitySwitch               matlab.ui.control.Switch
        BistabilitySwitchLabel          matlab.ui.control.Label
        ThresholdVariabilitySwitch      matlab.ui.control.Switch
        ThresholdVariabilitySwitchLabel  matlab.ui.control.Label
        ReboundBurstSwitch              matlab.ui.control.Switch
        ReboundBurstSwitchLabel         matlab.ui.control.Label
        ReboundSpikeSwitch              matlab.ui.control.Switch
        ReboundSpikeSwitchLabel         matlab.ui.control.Label
        IntegratorSwitch                matlab.ui.control.Switch
        IntegratorSwitchLabel           matlab.ui.control.Label
        ResonatorSwitch                 matlab.ui.control.Switch
        ResonatorSwitchLabel            matlab.ui.control.Label
        SubthresholdSwitch              matlab.ui.control.Switch
        SubthresholdSwitchLabel         matlab.ui.control.Label
        Class2excitableSwitch           matlab.ui.control.Switch
        Class2excitableSwitchLabel      matlab.ui.control.Label
        Class1ExcitableSwitch           matlab.ui.control.Switch
        Class1ExcitableSwitchLabel      matlab.ui.control.Label
        SpikeLatencySwitch              matlab.ui.control.Switch
        SpikeLatencySwitchLabel         matlab.ui.control.Label
        SpikeFrequencyAdaptativeSwitch  matlab.ui.control.Switch
        SpikeFrequencyAdaptativeSwitchLabel  matlab.ui.control.Label
        MixedModeSwitch                 matlab.ui.control.Switch
        MixedModeSwitchLabel            matlab.ui.control.Label
        PhasicBurstingSwitch            matlab.ui.control.Switch
        PhasicBurstingSwitchLabel       matlab.ui.control.Label
        TonicBurstingSwitch             matlab.ui.control.Switch
        TonicBurstingSwitchLabel        matlab.ui.control.Label
        PhasicSpikingSwitch             matlab.ui.control.Switch
        PhasicSpikingSwitchLabel        matlab.ui.control.Label
        TiposdeneuronasLabel            matlab.ui.control.Label
        TonicSpikingSwitch              matlab.ui.control.Switch
        TonicSpikingSwitchLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        type; % Description
        selection;
        estructura_neuronas;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tipo_neuronas_struct)
            app.estructura_neuronas = tipo_neuronas_struct;
            app.UIFigure.Name = 'Tipos de neuronas';
            app.TonicSpikingSwitch.Value = 'On';
            app.TonicBurstingSwitch.Value = 'On';
            app.PhasicBurstingSwitch.Value = 'On';
            app.PhasicSpikingSwitch.Value = 'On';

            app.TonicSpikingSwitch.FontColor = [1,0,0];
            app.TonicBurstingSwitch.FontColor = [1,0,0];
            app.PhasicBurstingSwitch.FontColor = [1,0,0];
            app.PhasicSpikingSwitch.FontColor = [1,0,0];

            app.TonicSpikingSwitchLabel.FontColor = [1,0,0];
            app.TonicBurstingSwitchLabel.FontColor = [1,0,0];
            app.PhasicBurstingSwitchLabel.FontColor = [1,0,0];
            app.PhasicSpikingSwitchLabel.FontColor = [1,0,0];

            app.estructura_neuronas(1).eleccion = 1;
            app.estructura_neuronas(2).eleccion = 1;
            app.estructura_neuronas(3).eleccion = 1;
            app.estructura_neuronas(4).eleccion = 1;
        end

        % Button pushed function: grafica_tonic_spiking
        function grafica_tonic_spikingPushed(app, event)
            app.type = 1;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: phasic_spiking
        function phasic_spikingPushed(app, event)
            app.type = 2;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: tonic_bursting
        function tonic_burstingPushed(app, event)
            app.type = 3;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: phasic_bursting
        function phasic_burstingPushed(app, event)
            app.type = 4;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: mixed_mode
        function mixed_modePushed(app, event)
            app.type = 5;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: spike_freq_adap
        function spike_freq_adapPushed(app, event)
            app.type = 6;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: spike_latency
        function spike_latencyPushed(app, event)
            app.type = 7;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: class1_excitable
        function class1_excitablePushed(app, event)
            app.type = 8;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: class2_excitable
        function class2_excitablePushed(app, event)
            app.type = 9;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: subthreshold
        function subthresholdPushed(app, event)
            app.type = 10;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: resonator
        function resonatorPushed(app, event)
            app.type = 11;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: integrator
        function integratorPushed(app, event)
            app.type = 12;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: rebound_spike
        function rebound_spikePushed(app, event)
            app.type = 13;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: rebound_burst
        function rebound_burstPushed(app, event)
            app.type = 14;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: threshold_variability
        function threshold_variabilityPushed(app, event)
            app.type = 15;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: bistability
        function bistabilityPushed(app, event)
            app.type = 16;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: DAP
        function DAPPushed(app, event)
            app.type = 17;
            grafica_tipo_neurona(app.type)
        end

        % Button pushed function: accomodation
        function accomodationPushed(app, event)
            app.type = 18;
            grafica_tipo_neurona(app.type)
        end

        % Value changed function: TonicSpikingSwitch
        function TonicSpikingSwitchValueChanged(app, event)
            value_tonic_spiking = app.TonicSpikingSwitch.Value;
            if strcmp(value_tonic_spiking,'On') == 1
                app.estructura_neuronas(1).eleccion = 1;
                app.TonicSpikingSwitch.FontColor = [0,0,1];
                app.TonicSpikingSwitchLabel.FontColor = [0,0,1];
            elseif strcmp(value_tonic_spiking, 'Off') == 1
                app.estructura_neuronas(1).eleccion = 0;
                app.TonicSpikingSwitch.FontColor = [0.5,0.5,0.5];
                app.TonicSpikingSwitchLabel.FontColor = [0.5,0.5,0.5];
            end
        end

        % Value changed function: PhasicSpikingSwitch
        function PhasicSpikingSwitchValueChanged(app, event)
            value_phasic_spiking = app.PhasicSpikingSwitch.Value;
            if strcmp(value_phasic_spiking,'On') == 1
                app.estructura_neuronas(2).eleccion = 1;
                app.PhasicSpikingSwitch.FontColor = [0,0,1];
                app.PhasicSpikingSwitchLabel.FontColor = [0,0,1];
            elseif strcmp(value_phasic_spiking, 'Off') == 1
                app.estructura_neuronas(2).eleccion = 0;
                app.PhasicSpikingSwitch.FontColor = [0.5,0.5,0.5];
                app.PhasicSpikingSwitchLabel.FontColor = [0.5,0.5,0.5];
            end
        end

        % Value changed function: TonicBurstingSwitch
        function TonicBurstingSwitchValueChanged(app, event)
            value_tonic_bursting = app.TonicBurstingSwitch.Value;
            if strcmp(value_tonic_bursting,'On') == 1
                app.estructura_neuronas(3).eleccion = 1;
                app.TonicBurstingSwitch.FontColor = [0,0,1];
                app.TonicBurstingSwitchLabel.FontColor = [0,0,1];
            elseif strcmp(value_tonic_bursting, 'Off') == 1
                app.estructura_neuronas(3).eleccion = 0;
                app.TonicBurstingSwitch.FontColor = [0.5,0.5,0.5];
                app.TonicBurstingSwitchLabel.FontColor = [0.5,0.5,0.5];
            end           
            
        end

        % Value changed function: PhasicBurstingSwitch
        function PhasicBurstingSwitchValueChanged(app, event)
            value_phasic_bursting = app.PhasicBurstingSwitch.Value;
            if strcmp(value_phasic_bursting,'On') == 1
                app.estructura_neuronas(3).eleccion = 1;
                app.PhasicBurstingSwitch.FontColor = [0,0,1];
                app.PhasicBurstingSwitchLabel.FontColor = [0,0,1];
            elseif strcmp(value_phasic_bursting, 'Off') == 1
                app.estructura_neuronas(3).eleccion = 0;
                app.PhasicBurstingSwitch.FontColor = [0.5,0.5,0.5];
                app.PhasicBurstingSwitchLabel.FontColor = [0.5,0.5,0.5];
            end   
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TonicSpikingSwitchLabel = uilabel(app.UIFigure);
            app.TonicSpikingSwitchLabel.Text = 'Tonic Spiking';
            app.TonicSpikingSwitch = uiswitch(app.UIFigure, 'slider');
            app.TonicSpikingSwitch.ValueChangedFcn = createCallbackFcn(app, @TonicSpikingSwitchValueChanged, true);
            app.TiposdeneuronasLabel = uilabel(app.UIFigure);
            app.TiposdeneuronasLabel.Text = 'Tipos de neuronas';
            app.PhasicSpikingSwitchLabel = uilabel(app.UIFigure);
            app.PhasicSpikingSwitchLabel.Text = 'Phasic Spiking';
            app.PhasicSpikingSwitch = uiswitch(app.UIFigure, 'slider');
            app.PhasicSpikingSwitch.ValueChangedFcn = createCallbackFcn(app, @PhasicSpikingSwitchValueChanged, true);
            app.TonicBurstingSwitchLabel = uilabel(app.UIFigure);
            app.TonicBurstingSwitchLabel.Text = 'Tonic Bursting';
            app.TonicBurstingSwitch = uiswitch(app.UIFigure, 'slider');
            app.TonicBurstingSwitch.ValueChangedFcn = createCallbackFcn(app, @TonicBurstingSwitchValueChanged, true);
            app.PhasicBurstingSwitchLabel = uilabel(app.UIFigure);
            app.PhasicBurstingSwitchLabel.Text = 'Phasic Bursting';
            app.PhasicBurstingSwitch = uiswitch(app.UIFigure, 'slider');
            app.PhasicBurstingSwitch.ValueChangedFcn = createCallbackFcn(app, @PhasicBurstingSwitchValueChanged, true);
            app.MixedModeSwitchLabel = uilabel(app.UIFigure);
            app.MixedModeSwitchLabel.Text = 'Mixed Mode';
            app.MixedModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.SpikeFrequencyAdaptativeSwitchLabel = uilabel(app.UIFigure);
            app.SpikeFrequencyAdaptativeSwitchLabel.Text = 'Spike Frequency Adaptative';
            app.SpikeFrequencyAdaptativeSwitch = uiswitch(app.UIFigure, 'slider');
            app.SpikeLatencySwitchLabel = uilabel(app.UIFigure);
            app.SpikeLatencySwitchLabel.Text = 'Spike Latency';
            app.SpikeLatencySwitch = uiswitch(app.UIFigure, 'slider');
            app.Class1ExcitableSwitchLabel = uilabel(app.UIFigure);
            app.Class1ExcitableSwitchLabel.Text = 'Class 1 Excitable';
            app.Class1ExcitableSwitch = uiswitch(app.UIFigure, 'slider');
            app.Class2excitableSwitchLabel = uilabel(app.UIFigure);
            app.Class2excitableSwitchLabel.Text = 'Class 2 excitable';
            app.Class2excitableSwitch = uiswitch(app.UIFigure, 'slider');
            app.SubthresholdSwitchLabel = uilabel(app.UIFigure);
            app.SubthresholdSwitchLabel.Text = 'Subthreshold';
            app.SubthresholdSwitch = uiswitch(app.UIFigure, 'slider');
            app.ResonatorSwitchLabel = uilabel(app.UIFigure);
            app.ResonatorSwitchLabel.Text = 'Resonator';
            app.ResonatorSwitch = uiswitch(app.UIFigure, 'slider');
            app.IntegratorSwitchLabel = uilabel(app.UIFigure);
            app.IntegratorSwitchLabel.Text = 'Integrator';
            app.IntegratorSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReboundSpikeSwitchLabel = uilabel(app.UIFigure);
            app.ReboundSpikeSwitchLabel.Text = 'Rebound Spike';
            app.ReboundSpikeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReboundBurstSwitchLabel = uilabel(app.UIFigure);
            app.ReboundBurstSwitchLabel.Text = 'Rebound Burst';
            app.ReboundBurstSwitch = uiswitch(app.UIFigure, 'slider');
            app.ThresholdVariabilitySwitchLabel = uilabel(app.UIFigure);
            app.ThresholdVariabilitySwitchLabel.Text = 'Threshold Variability';
            app.ThresholdVariabilitySwitch = uiswitch(app.UIFigure, 'slider');
            app.BistabilitySwitchLabel = uilabel(app.UIFigure);
            app.BistabilitySwitchLabel.Text = 'Bistability';
            app.BistabilitySwitch = uiswitch(app.UIFigure, 'slider');
            app.DAPSwitchLabel = uilabel(app.UIFigure);
            app.DAPSwitchLabel.Text = 'DAP';
            app.DAPSwitch = uiswitch(app.UIFigure, 'slider');
            app.AccomodationSwitchLabel = uilabel(app.UIFigure);
            app.AccomodationSwitchLabel.Text = 'Accomodation';
            app.AccomodationSwitch = uiswitch(app.UIFigure, 'slider');
            app.indicacion = uilabel(app.UIFigure);
            app.indicacion.Text = 'Selecciona los tipos de neuronas que deseas incluir en tu red neuronal simulada';
            app.grafica_tonic_spiking = uibutton(app.UIFigure, 'push');
            app.grafica_tonic_spiking.ButtonPushedFcn = createCallbackFcn(app, @grafica_tonic_spikingPushed, true);
            app.grafica_tonic_spiking.Tag = 'Ver gráfica';
            app.grafica_tonic_spiking.Icon = 'icono_ayuda.png';
            app.grafica_tonic_spiking.Tooltip = {'Ver gráfica'; ''};
            app.grafica_tonic_spiking.Text = '';
            app.mixed_mode = uibutton(app.UIFigure, 'push');
            app.mixed_mode.ButtonPushedFcn = createCallbackFcn(app, @mixed_modePushed, true);
            app.mixed_mode.Icon = 'icono_ayuda.png';
            app.mixed_mode.Tooltip = {'Ver gráfica'};
            app.mixed_mode.Text = '';
            app.class2_excitable = uibutton(app.UIFigure, 'push');
            app.class2_excitable.ButtonPushedFcn = createCallbackFcn(app, @class2_excitablePushed, true);
            app.class2_excitable.Icon = 'icono_ayuda.png';
            app.class2_excitable.Tooltip = {'Ver gráfica'};
            app.class2_excitable.Text = '';
            app.phasic_spiking = uibutton(app.UIFigure, 'push');
            app.phasic_spiking.ButtonPushedFcn = createCallbackFcn(app, @phasic_spikingPushed, true);
            app.phasic_spiking.Icon = 'icono_ayuda.png';
            app.phasic_spiking.Tooltip = {'Ver gráfica'};
            app.phasic_spiking.Text = '';
            app.spike_freq_adap = uibutton(app.UIFigure, 'push');
            app.spike_freq_adap.ButtonPushedFcn = createCallbackFcn(app, @spike_freq_adapPushed, true);
            app.spike_freq_adap.Icon = 'icono_ayuda.png';
            app.spike_freq_adap.Tooltip = {'Ver gráfica'};
            app.spike_freq_adap.Text = '';
            app.subthreshold = uibutton(app.UIFigure, 'push');
            app.subthreshold.ButtonPushedFcn = createCallbackFcn(app, @subthresholdPushed, true);
            app.subthreshold.Icon = 'icono_ayuda.png';
            app.subthreshold.Tooltip = {'Ver gráfica'};
            app.subthreshold.Text = '';
            app.tonic_bursting = uibutton(app.UIFigure, 'push');
            app.tonic_bursting.ButtonPushedFcn = createCallbackFcn(app, @tonic_burstingPushed, true);
            app.tonic_bursting.Icon = 'icono_ayuda.png';
            app.tonic_bursting.Tooltip = {'Ver gráfica'};
            app.tonic_bursting.Text = '';
            app.spike_latency = uibutton(app.UIFigure, 'push');
            app.spike_latency.ButtonPushedFcn = createCallbackFcn(app, @spike_latencyPushed, true);
            app.spike_latency.Icon = 'icono_ayuda.png';
            app.spike_latency.Tooltip = {'Ver gráfica'};
            app.spike_latency.Text = '';
            app.resonator = uibutton(app.UIFigure, 'push');
            app.resonator.ButtonPushedFcn = createCallbackFcn(app, @resonatorPushed, true);
            app.resonator.Icon = 'icono_ayuda.png';
            app.resonator.Tooltip = {'Ver gráfica'};
            app.resonator.Text = '';
            app.phasic_bursting = uibutton(app.UIFigure, 'push');
            app.phasic_bursting.ButtonPushedFcn = createCallbackFcn(app, @phasic_burstingPushed, true);
            app.phasic_bursting.Icon = 'icono_ayuda.png';
            app.phasic_bursting.Tooltip = {'Ver gráfica'};
            app.phasic_bursting.Text = '';
            app.class1_excitable = uibutton(app.UIFigure, 'push');
            app.class1_excitable.ButtonPushedFcn = createCallbackFcn(app, @class1_excitablePushed, true);
            app.class1_excitable.Icon = 'icono_ayuda.png';
            app.class1_excitable.Tooltip = {'Ver gráfica'};
            app.class1_excitable.Text = '';
            app.integrator = uibutton(app.UIFigure, 'push');
            app.integrator.ButtonPushedFcn = createCallbackFcn(app, @integratorPushed, true);
            app.integrator.Icon = 'icono_ayuda.png';
            app.integrator.Tooltip = {'Ver gráfica'};
            app.integrator.Text = '';
            app.rebound_spike = uibutton(app.UIFigure, 'push');
            app.rebound_spike.ButtonPushedFcn = createCallbackFcn(app, @rebound_spikePushed, true);
            app.rebound_spike.Icon = 'icono_ayuda.png';
            app.rebound_spike.Tooltip = {'Ver gráfica'};
            app.rebound_spike.Text = '';
            app.threshold_variability = uibutton(app.UIFigure, 'push');
            app.threshold_variability.ButtonPushedFcn = createCallbackFcn(app, @threshold_variabilityPushed, true);
            app.threshold_variability.Icon = 'icono_ayuda.png';
            app.threshold_variability.Tooltip = {'Ver gráfica'};
            app.threshold_variability.Text = '';
            app.rebound_burst = uibutton(app.UIFigure, 'push');
            app.rebound_burst.ButtonPushedFcn = createCallbackFcn(app, @rebound_burstPushed, true);
            app.rebound_burst.Icon = 'icono_ayuda.png';
            app.rebound_burst.Tooltip = {'Ver gráfica'};
            app.rebound_burst.Text = '';
            app.bistability = uibutton(app.UIFigure, 'push');
            app.bistability.ButtonPushedFcn = createCallbackFcn(app, @bistabilityPushed, true);
            app.bistability.Icon = 'icono_ayuda.png';
            app.bistability.Tooltip = {'Ver gráfica'};
            app.bistability.Text = '';
            app.DAP = uibutton(app.UIFigure, 'push');
            app.DAP.ButtonPushedFcn = createCallbackFcn(app, @DAPPushed, true);
            app.DAP.Icon = 'icono_ayuda.png';
            app.DAP.Tooltip = {'Ver gráfica'};
            app.DAP.Text = '';
            app.accomodation = uibutton(app.UIFigure, 'push');
            app.accomodation.ButtonPushedFcn = createCallbackFcn(app, @accomodationPushed, true);
            app.accomodation.Icon = 'icono_ayuda.png';
            app.accomodation.Tooltip = {'Ver gráfica'};
            app.accomodation.Text = '';
            app.example_icono = uibutton(app.UIFigure, 'push');
            app.example_icono.Icon = 'icono_ayuda.png';
            app.example_icono.Text = '';
            app.NotaLabel = uilabel(app.UIFigure);
            app.NotaLabel.Text = 'Nota:';
            app.PresionaelbotnLabel = uilabel(app.UIFigure);
            app.PresionaelbotnLabel.Text = 'Presiona el botón';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'de cada tipo de neurona para observar su comportamiento de manera gráfica';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tipos_neuronas(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end